classdef my_traffic_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RoadALabel       matlab.ui.control.Label
        RoadBLabel       matlab.ui.control.Label
        Switch           matlab.ui.control.Switch
        ModeButtonGroup  matlab.ui.container.ButtonGroup
        FaultButton      matlab.ui.control.RadioButton
        NormalButton     matlab.ui.control.RadioButton
        V2Lamp           matlab.ui.control.Lamp
        O2Lamp           matlab.ui.control.Lamp
        R2Lamp           matlab.ui.control.Lamp
        V1Lamp           matlab.ui.control.Lamp
        O1Lamp           matlab.ui.control.Lamp
        R1Lamp           matlab.ui.control.Lamp
    end

    properties (Access = private)
        trafficLogic % Description
        normalState
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.trafficLogic = my_traffic_logic('app',app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch lower(value)
                case "off"
                    OFF(app.trafficLogic);
                case "on"
                    ON(app.trafficLogic);
            end
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            if selectedButton == app.NormalButton
                NORMAL(app.trafficLogic);
            else
                FAULT(app.trafficLogic);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.trafficLogic);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.R1Lamp = uilamp(app.UIFigure);
            app.O1Lamp = uilamp(app.UIFigure);
            app.V1Lamp = uilamp(app.UIFigure);
            app.R2Lamp = uilamp(app.UIFigure);
            app.O2Lamp = uilamp(app.UIFigure);
            app.V2Lamp = uilamp(app.UIFigure);
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.NormalButton = uiradiobutton(app.ModeButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.FaultButton = uiradiobutton(app.ModeButtonGroup);
            app.FaultButton.Text = 'Fault';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.RoadBLabel = uilabel(app.UIFigure);
            app.RoadBLabel.Text = 'RoadB';
            app.RoadALabel = uilabel(app.UIFigure);
            app.RoadALabel.Text = 'RoadA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_traffic_light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end